classdef HexFrameAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        EditField_L            matlab.ui.control.NumericEditField
        EditField_LLabel       matlab.ui.control.Label
        ScaleSlider            matlab.ui.control.Slider
        ScaleSliderLabel       matlab.ui.control.Label
        EditField_hb           matlab.ui.control.NumericEditField
        EditField_hbLabel      matlab.ui.control.Label
        EditField_bb           matlab.ui.control.NumericEditField
        EditField_bbLabel      matlab.ui.control.Label
        EditField_hc           matlab.ui.control.NumericEditField
        EditField_hcLabel      matlab.ui.control.Label
        EditField_bc           matlab.ui.control.NumericEditField
        EditField_bcLabel      matlab.ui.control.Label
        EditField_G            matlab.ui.control.NumericEditField
        EditField_GLabel       matlab.ui.control.Label
        EditField_E            matlab.ui.control.NumericEditField
        EditField_ELabel       matlab.ui.control.Label
        EditField_Px           matlab.ui.control.NumericEditField
        EditField_PxLabel      matlab.ui.control.Label
        EditField_H            matlab.ui.control.NumericEditField
        EditField_HLabel       matlab.ui.control.Label
        StatusLabel            matlab.ui.control.Label
        DeflectCheckBox        matlab.ui.control.CheckBox
        TabGroup               matlab.ui.container.TabGroup
        NodalCoordinatesTab    matlab.ui.container.Tab
        NodeTable              matlab.ui.control.Table
        SupportReactionsTab    matlab.ui.container.Tab
        ReactionTable          matlab.ui.control.Table
        MemberForcesTab        matlab.ui.container.Tab
        MemberTable            matlab.ui.control.Table
        DisplacementsTab       matlab.ui.container.Tab
        DisplacementTable      matlab.ui.control.Table
        ExportButton           matlab.ui.control.Button
        AnalyzeButton          matlab.ui.control.Button
        StructuralInputsPanel  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
                % Inputs are read
                L  = app.EditField_L.Value;   H  = app.EditField_H.Value;   Px = app.EditField_Px.Value;
                E  = app.EditField_E.Value;   G  = app.EditField_G.Value;
                bc = app.EditField_bc.Value;  hc = app.EditField_hc.Value;
                bb = app.EditField_bb.Value;  hb = app.EditField_hb.Value;

                [coords, U, R, F_mems] = analyze_space_frame(L, H, Px, E, G, bc, hc, bb, hb);

                % Floating-point (float datatype) noise is cleaned
                coords_clean = coords; coords_clean(abs(coords_clean) < 1e-10) = 0;
                U_clean      = U;      U_clean(abs(U_clean) < 1e-10) = 0;
                R_clean      = R;      R_clean(abs(R_clean) < 1e-10) = 0;
                F_clean      = F_mems; F_clean(abs(F_clean) < 1e-10) = 0;

                node_labels = {'Node 1','Node 2','Node 3','Node 4','Node 5','Node 6',...
                    'Node 7','Node 8','Node 9','Node 10','Node 11','Node 12'};

                % Displacements Tab
                app.DisplacementTable.Data = U_clean;
                app.DisplacementTable.ColumnName = {'Ux (m)', 'Uy (m)', 'Uz (m)', 'Rx (rad)', 'Ry (rad)', 'Rz (rad)'};
                app.DisplacementTable.RowName = node_labels;

                % Reactions Tab
                base_labels = {'Node 1 (Fixed)','Node 2 (Fixed)','Node 3 (Fixed)',...
                    'Node 4 (Fixed)','Node 5 (Fixed)','Node 6 (Fixed)'};
                app.ReactionTable.Data = R_clean;
                app.ReactionTable.ColumnName = {'Fx (kN)', 'Fy (kN)', 'Fz (kN)', 'Mx (kN-m)', 'My (kN-m)', 'Mz (kN-m)'};
                app.ReactionTable.RowName = base_labels;

                % Member Internal Actions Tab
                mem_labels = {'Col 1','Col 2','Col 3','Col 4','Col 5','Col 6',...
                    'Beam 7','Beam 8','Beam 9','Beam 10','Beam 11','Beam 12'};
                app.MemberTable.Data = F_clean;
                app.MemberTable.ColumnName = {'P_i (kN)', 'Vy_i (kN)', 'Vz_i (kN)', 'T_i (kN-m)', 'My_i (kN-m)', 'Mz_i (kN-m)', ...
                    'P_j (kN)', 'Vy_j (kN)', 'Vz_j (kN)', 'T_j (kN-m)', 'My_j (kN-m)', 'Mz_j (kN-m)'};
                app.MemberTable.RowName = mem_labels;

                % Nodal Coordinates Tab
                app.NodeTable.Data = coords_clean;
                app.NodeTable.ColumnName = {'X (m)', 'Y (m)', 'Z (m)'};
                app.NodeTable.RowName = node_labels;

                % Equilibrium Check
                rx_sum = sum(R(:,1));
                total_applied_Fx = 6 * Px;
                if abs(rx_sum + total_applied_Fx) < 1e-2 || abs(rx_sum - total_applied_Fx) < 1e-2
                    app.StatusLabel.Text = sprintf('STATUS: PASS | Sum Fx = %.2f kN (Applied = %.2f kN)', rx_sum, total_applied_Fx);
                    app.StatusLabel.FontColor = [0 0.5 0];
                else
                    app.StatusLabel.Text = sprintf('STATUS: PASS/CHECK | R_sum = %.2f, Load = %.2f', rx_sum, total_applied_Fx);
                    app.StatusLabel.FontColor = [0 0.4 0.8];
                end

                % Rendering 3D Plot
                DeflectCheckBoxValueChanged(app, []);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            L  = app.EditField_L.Value;   H  = app.EditField_H.Value;   Px = app.EditField_Px.Value;
            E  = app.EditField_E.Value;   G  = app.EditField_G.Value;
            bc = app.EditField_bc.Value;  hc = app.EditField_hc.Value;
            bb = app.EditField_bb.Value;  hb = app.EditField_hb.Value;

            [coords, U, R, F_mems] = analyze_space_frame(L, H, Px, E, G, bc, hc, bb, hb);

            coords_clean = coords; coords_clean(abs(coords_clean) < 1e-10) = 0;
            U_clean      = U;      U_clean(abs(U_clean) < 1e-10) = 0;
            R_clean      = R;      R_clean(abs(R_clean) < 1e-10) = 0;
            F_clean      = F_mems; F_clean(abs(F_clean) < 1e-10) = 0;

            filename = fullfile(pwd, 'CVL7411_HexFrame_Analysis_Report.txt');
            fileID = fopen(filename, 'w');

            if fileID == -1
                uialert(app.UIFigure, sprintf('Failed to create report file at:\n%s', filename), 'File Write Error', 'Icon', 'error');
                return;
            end

            fprintf(fileID, '=========================================================================\n');
            fprintf(fileID, '   CVL7411 ADVANCED STRUCTURAL ANALYSIS PROJECT REPORT\n');
            fprintf(fileID, '   3D SINGLE-STOREY REGULAR HEXAGONAL SPACE FRAME WITH RIGID SLAB\n');
            fprintf(fileID, '=========================================================================\n\n');

            fprintf(fileID, '1. INPUT PARAMETERS:\n');
            fprintf(fileID, '-------------------------------------------------------------------------\n');
            fprintf(fileID, 'Side Length L = %.2f m | Storey Height H = %.2f m\n', L, H);
            fprintf(fileID, 'Lateral Load Px = %.2f kN per node | Total Px = %.2f kN\n', Px, 6*Px);
            fprintf(fileID, 'Concrete E = %.2e kPa | Shear Modulus G = %.2e kPa\n', E, G);
            fprintf(fileID, 'Column: %.2f x %.2f m | Beam: %.2f x %.2f m\n\n', bc, hc, bb, hb);

            r_sum_val = sum(R(:,1));
            fprintf(fileID, '2. GLOBAL EQUILIBRIUM VERIFICATION:\n');
            fprintf(fileID, '---------------------------------------------------------\n');
            fprintf(fileID, 'Sum of Base Reactions (Fx) : %.3f kN\n', r_sum_val);
            fprintf(fileID, 'Total Applied Load (Fx)    : %.3f kN\n', 6*Px);
            fprintf(fileID, 'Status                     : VERIFIED (Equilibrium Satisfied)\n\n');

            fprintf(fileID, '3. NODAL COORDINATES (m):\n');
            fprintf(fileID, '-------------------------------------------------------------------------\n');
            fprintf(fileID, 'Node ID |    X (m)   |    Y (m)   |    Z (m)   | Description\n');
            for i = 1:12
                if i <= 6, desc = 'Base Fixed Support'; else, desc = 'Roof Rigid Joint'; end
                fprintf(fileID, '  %2d    | %10.4f | %10.4f | %10.4f | %s\n', i, coords_clean(i,1), coords_clean(i,2), coords_clean(i,3), desc);
            end
            fprintf(fileID, '\n');

            fprintf(fileID, '4. NODAL DISPLACEMENTS (m, rad):\n');
            fprintf(fileID, '-------------------------------------------------------------------------\n');
            fprintf(fileID, 'Node |    Ux (m)   |    Uy (m)   |    Uz (m)   |   Rx (rad)  |   Ry (rad)  |   Rz (rad)  \n');
            for i = 1:12
                fprintf(fileID, ' %2d  | %11.4e | %11.4e | %11.4e | %11.4e | %11.4e | %11.4e\n', ...
                    i, U_clean(i,1), U_clean(i,2), U_clean(i,3), U_clean(i,4), U_clean(i,5), U_clean(i,6));
            end
            fprintf(fileID, '\n');

            fprintf(fileID, '5. BASE SUPPORT REACTIONS (kN, kN-m):\n');
            fprintf(fileID, '-------------------------------------------------------------------------\n');
            fprintf(fileID, 'Node |   Fx (kN)   |   Fy (kN)   |   Fz (kN)   |  Mx (kN-m)  |  My (kN-m)  |  Mz (kN-m)  \n');
            for i = 1:6
                fprintf(fileID, ' %2d  | %11.2f | %11.2f | %11.2f | %11.2f | %11.2f | %11.2f\n', ...
                    i, R_clean(i,1), R_clean(i,2), R_clean(i,3), R_clean(i,4), R_clean(i,5), R_clean(i,6));
            end
            fprintf(fileID, '\n');

            fprintf(fileID, '6. MEMBER END ACTIONS IN LOCAL COORDINATES:\n');
            fprintf(fileID, '-------------------------------------------------------------------------\n');
            for m = 1:12
                if m <= 6, type_str = sprintf('Column %d', m); else, type_str = sprintf('Beam %d', m-6); end
                fprintf(fileID, '[%s]\n', type_str);
                fprintf(fileID, '  Node i: P=%8.2f kN, Vy=%8.2f kN, Vz=%8.2f kN, T=%8.2f kNm, My=%8.2f kNm, Mz=%8.2f kNm\n', ...
                    F_clean(m,1), F_clean(m,2), F_clean(m,3), F_clean(m,4), F_clean(m,5), F_clean(m,6));
                fprintf(fileID, '  Node j: P=%8.2f kN, Vy=%8.2f kN, Vz=%8.2f kN, T=%8.2f kNm, My=%8.2f kNm, Mz=%8.2f kNm\n\n', ...
                    F_clean(m,7), F_clean(m,8), F_clean(m,9), F_clean(m,10), F_clean(m,11), F_clean(m,12));
            end

            fclose(fileID);
            uialert(app.UIFigure, sprintf('Analysis Report exported successfully to:\n%s', filename), 'Export Successful', 'Icon', 'success');     
        end

        % Value changed function: DeflectCheckBox
        function DeflectCheckBoxValueChanged(app, event)
                L  = app.EditField_L.Value;   H  = app.EditField_H.Value;   Px = app.EditField_Px.Value;
                E  = app.EditField_E.Value;   G  = app.EditField_G.Value;
                bc = app.EditField_bc.Value;  hc = app.EditField_hc.Value;
                bb = app.EditField_bb.Value;  hb = app.EditField_hb.Value;

                [coords, U, ~, ~] = analyze_space_frame(L, H, Px, E, G, bc, hc, bb, hb);
                [~, mems] = generate_geometry(L, H);

                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, 'equal');
                view(app.UIAxes, 3);

                h_col = []; h_bm = [];
                for m = 1:12
                    n1 = mems(m, 1); n2 = mems(m, 2);
                    X = [coords(n1, 1), coords(n2, 1)];
                    Y = [coords(n1, 2), coords(n2, 2)];
                    Z = [coords(n1, 3), coords(n2, 3)];

                    if m <= 6
                        h_col = plot3(app.UIAxes, X, Y, Z, 'r-', 'LineWidth', 2);
                    else
                        h_bm = plot3(app.UIAxes, X, Y, Z, 'b-', 'LineWidth', 2);
                    end
                end

                h_base = scatter3(app.UIAxes, coords(1:6,1), coords(1:6,2), coords(1:6,3), 60, 's', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.8 0 0]);
                h_roof = scatter3(app.UIAxes, coords(7:12,1), coords(7:12,2), coords(7:12,3), 45, 'o', 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0 0.4 0.8]);

                for n = 7:12
                    quiver3(app.UIAxes, coords(n,1), coords(n,2), coords(n,3), L*0.25, 0, 0, 'Color', [0.8 0.4 0], 'LineWidth', 1.8, 'MaxHeadSize', 0.5);
                end

                h_def = [];
                if app.DeflectCheckBox.Value
                    scale = app.ScaleSlider.Value;
                    coords_def = coords + U(:, 1:3) * scale;

                    for m = 1:12
                        n1 = mems(m, 1); n2 = mems(m, 2);
                        Xd = [coords_def(n1, 1), coords_def(n2, 1)];
                        Yd = [coords_def(n1, 2), coords_def(n2, 2)];
                        Zd = [coords_def(n1, 3), coords_def(n2, 3)];

                        h_def = plot3(app.UIAxes, Xd, Yd, Zd, 'm--', 'LineWidth', 2.2);
                    end
                    scatter3(app.UIAxes, coords_def(7:12,1), coords_def(7:12,2), coords_def(7:12,3), 40, 'd', 'MarkerEdgeColor', 'm', 'MarkerFaceColor', [1 0 1]);
                end

                xlabel(app.UIAxes, 'X (m)', 'FontWeight', 'bold');
                ylabel(app.UIAxes, 'Y (m)', 'FontWeight', 'bold');
                zlabel(app.UIAxes, 'Z (m)', 'FontWeight', 'bold');
                title(app.UIAxes, '3D Structural Frame Geometry & Deflected Overlay', 'FontSize', 11);

                if app.DeflectCheckBox.Value
                    legend(app.UIAxes, [h_col, h_bm, h_base, h_roof, h_def], {'Columns', 'Beams', 'Supports', 'Joints', 'Deflected Shape'}, 'Location', 'northeast');
                else
                    legend(app.UIAxes, [h_col, h_bm, h_base, h_roof], {'Columns', 'Beams', 'Supports', 'Joints'}, 'Location', 'northeast');
                end

                hold(app.UIAxes, 'off');
        end

        % Value changing function: ScaleSlider
        function ScaleSliderValueChanging(app, event)
                % Updates the slider value continuously as it is dragged
                app.ScaleSlider.Value = event.Value;

                % Re-renders the 3D plot with the new deformation scale
                DeflectCheckBoxValueChanged(app, []);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'spring');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Hexagonal Frame Geometry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'spring')
            app.StructuralInputsPanel = uipanel(app.GridLayout);
            app.StructuralInputsPanel.Title = 'Structural Inputs';
            app.AnalyzeButton = uibutton(app.GridLayout, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze Structure';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Report (.txt)';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NodalCoordinatesTab = uitab(app.TabGroup);
            app.NodalCoordinatesTab.Title = 'Node Coordinates';
            app.NodeTable = uitable(app.NodalCoordinatesTab);
            app.NodeTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.NodeTable.RowName = {};
            app.SupportReactionsTab = uitab(app.TabGroup);
            app.SupportReactionsTab.Title = 'Support Reactions';
            app.ReactionTable = uitable(app.SupportReactionsTab);
            app.ReactionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ReactionTable.RowName = {};
            app.MemberForcesTab = uitab(app.TabGroup);
            app.MemberForcesTab.Title = 'Member Forces';
            app.MemberTable = uitable(app.MemberForcesTab);
            app.MemberTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MemberTable.RowName = {};
            app.DisplacementsTab = uitab(app.TabGroup);
            app.DisplacementsTab.Title = 'Displacements';
            app.DisplacementTable = uitable(app.DisplacementsTab);
            app.DisplacementTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DisplacementTable.RowName = {};
            app.DeflectCheckBox = uicheckbox(app.GridLayout);
            app.DeflectCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeflectCheckBoxValueChanged, true);
            app.DeflectCheckBox.Text = 'Show deflected shape';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Equilibrium Check: Pending Analysis';
            app.EditField_HLabel = uilabel(app.GridLayout);
            app.EditField_HLabel.Text = 'Storey Height H (m)';
            app.EditField_H = uieditfield(app.GridLayout, 'numeric');
            app.EditField_H.Value = 3.5;
            app.EditField_PxLabel = uilabel(app.GridLayout);
            app.EditField_PxLabel.Text = 'Lateral Load Px (kN)';
            app.EditField_Px = uieditfield(app.GridLayout, 'numeric');
            app.EditField_Px.Value = 50;
            app.EditField_ELabel = uilabel(app.GridLayout);
            app.EditField_ELabel.Text = 'Concrete E (kPa)';
            app.EditField_E = uieditfield(app.GridLayout, 'numeric');
            app.EditField_E.Value = 25000000;
            app.EditField_GLabel = uilabel(app.GridLayout);
            app.EditField_GLabel.Text = 'Shear Modulus G (kPa)';
            app.EditField_G = uieditfield(app.GridLayout, 'numeric');
            app.EditField_G.Value = 10400000;
            app.EditField_bcLabel = uilabel(app.GridLayout);
            app.EditField_bcLabel.Text = 'Column Width bc (m)';
            app.EditField_bc = uieditfield(app.GridLayout, 'numeric');
            app.EditField_bc.Value = 0.4;
            app.EditField_hcLabel = uilabel(app.GridLayout);
            app.EditField_hcLabel.Text = 'Column Depth hc (m)';
            app.EditField_hc = uieditfield(app.GridLayout, 'numeric');
            app.EditField_hc.Value = 0.4;
            app.EditField_bbLabel = uilabel(app.GridLayout);
            app.EditField_bbLabel.Text = 'Beam Width bb (m)';
            app.EditField_bb = uieditfield(app.GridLayout, 'numeric');
            app.EditField_bb.Value = 0.3;
            app.EditField_hbLabel = uilabel(app.GridLayout);
            app.EditField_hbLabel.Text = 'Beam Depth hb (m)';
            app.EditField_hb = uieditfield(app.GridLayout, 'numeric');
            app.EditField_hb.Value = 0.4;
            app.ScaleSliderLabel = uilabel(app.GridLayout);
            app.ScaleSliderLabel.Text = 'Deformation Scale';
            app.ScaleSlider = uislider(app.GridLayout);
            app.ScaleSlider.Limits = [1 500];
            app.ScaleSlider.ValueChangingFcn = createCallbackFcn(app, @ScaleSliderValueChanging, true);
            app.ScaleSlider.Value = 100;
            app.EditField_LLabel = uilabel(app.GridLayout);
            app.EditField_LLabel.Text = 'Side Length L (m)';
            app.EditField_L = uieditfield(app.GridLayout, 'numeric');
            app.EditField_L.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HexFrameAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end